classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        TabGroup              matlab.ui.container.TabGroup       % Real_com...
        Tab                   matlab.ui.container.Tab            % Real_com...
        LabelNumericEditField matlab.ui.control.Label            % COM:
        NumericEditField      matlab.ui.control.NumericEditField % [-Inf Inf]
        DropDown3             matlab.ui.control.DropDown         % CO, H2S,...
        LabelDropDown3        matlab.ui.control.Label            % DGS
        UIAxes4               matlab.ui.control.UIAxes           % Title
        DropDown4             matlab.ui.control.DropDown         % temperat...
        LabelDropDown4        matlab.ui.control.Label            % BME280
        UIAxes3               matlab.ui.control.UIAxes           % Title
        UIAxes2               matlab.ui.control.UIAxes           % Title
        DropDown              matlab.ui.control.DropDown         % Compass,...
        LabelDropDown         matlab.ui.control.Label            % AltIMU-10
        UIAxes                matlab.ui.control.UIAxes           % Title
        DropDown2             matlab.ui.control.DropDown         % Option 1...
        LabelDropDown2        matlab.ui.control.Label            % Drop Down
        StateButton2          matlab.ui.control.StateButton      % run com
        StateButton3          matlab.ui.control.StateButton      % Plot
        Tab2                  matlab.ui.container.Tab            % plot_data
        LabelEditField        matlab.ui.control.Label            % filename
        EditField             matlab.ui.control.EditField       
        UIAxes5               matlab.ui.control.UIAxes           % PPB of gas
        UIAxes6               matlab.ui.control.UIAxes           % Title
        UIAxes7               matlab.ui.control.UIAxes           % Title
        UIAxes8               matlab.ui.control.UIAxes           % Title
        DropDown5             matlab.ui.control.DropDown         % Compass,...
        Label                 matlab.ui.control.Label            % AltIMU-10
        DropDown6             matlab.ui.control.DropDown         % Option 1...
        Label2                matlab.ui.control.Label            % Drop Down
        DropDown7             matlab.ui.control.DropDown         % CO, H2S,...
        Label3                matlab.ui.control.Label            % DGS
        DropDown8             matlab.ui.control.DropDown         % temperat...
        Label4                matlab.ui.control.Label            % BME280
        Button                matlab.ui.control.Button           % plot
    end


    methods (Access = public)
        
        function results = odczyt(app)
            disp(3)
                     tic


     t = 0;
y = 1;


toc
         disp(3)   
        end


        function results = run_com(app)
            disp(2)
            s2 = int2str(get(app.NumericEditField, 'Value'));
            s1 = 'COM';
            SerialPort = strcat(s1,s2);
           % s = serial(SerialPort, 'BaudRate', 57600);
            %fopen(s);
            while get(app.StateButton3, 'Value');
            odczyt(app)
            end
              %   fclose(s);
          %  delete(s);
          %  clear s;
          disp(2)
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % StateButton2 value changed function
        function StateButton2ValueChanged(app)
            disp(1)
            run_com(app)  
            disp(1)
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            value1 = app.EditField.Value;
                       [o, ErrM] = fopen (value1, 'r');
        if(o == -1)
            warning(ErrM); 
            return;
        end;
        a = fscanf(o,'%f %f %f %f %f\n',[5, Inf])';
        b=[a(:,2),a(:,3),a(:,4),a(:,5)];
        d=[a(:,1)];
        fclose(o);
        serial_n1=061318011028;
        serial_n2=082918010321;
        serial_n3=102617010931;
        [m,n] = size(a);
        t_n1=1;
        t_n2=1;
        t_n3=1;
        tic
        for y=1:1:m
            if (a(y,1)==serial_n1)
               data_n1(t_n1,:)=[a(y,2),a(y,3),a(y,4),a(y,5)];
               t_n1=t_n1+1;
            end
             if (a(y,1)==serial_n2)
               data_n2(t_n2,:)=[a(y,2),a(y,3),a(y,4),a(y,5)];
               t_n2=t_n2+1;
             end
             if (a(y,1)==serial_n3)
               data_n3(t_n3,:)=[a(y,2),a(y,3),a(y,4),a(y,5)];
               t_n3=t_n3+1;
            end
        end
        toc
        
        switch (app.DropDown7.Value)
    case 'CO'
        x=data_n1(:,4);
        y1=data_n1(:,1);
        plot(app.UIAxes5,x,y1,'r-');
    case 'H2S'
        x=data_n2(:,4);
        y1=data_n2(:,1);
        plot(app.UIAxes5,x,y1,'r-');
            case 'SO2'
        x=data_n3(:,4);
        y1=data_n3(:,1);
        plot(app.UIAxes5,x,y1,'r-');
        end
        
        end

        % StateButton3 value changed function
        function StateButton3ValueChanged(app)
            value = app.StateButton3.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Real_com_data';
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = 'COM:';
            app.NumericEditField = uieditfield(app.Tab, 'numeric');
            app.DropDown3 = uidropdown(app.Tab);
            app.DropDown3.Items = {'CO', 'H2S', 'SO2', 'O3', 'NO2'};
            app.DropDown3.Value = 'CO';
            app.LabelDropDown3 = uilabel(app.Tab);
            app.LabelDropDown3.Text = 'DGS';
            app.UIAxes4 = uiaxes(app.Tab);
            title(app.UIAxes4, 'Title');
            xlabel(app.UIAxes4, 't [s]');
            ylabel(app.UIAxes4, 'Y');
            app.DropDown4 = uidropdown(app.Tab);
            app.DropDown4.Items = {'temperature', 'humidity', 'pressure'};
            app.DropDown4.Value = 'temperature';
            app.LabelDropDown4 = uilabel(app.Tab);
            app.LabelDropDown4.Text = 'BME280';
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 't [s]');
            ylabel(app.UIAxes3, 'Y');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 't [s]');
            ylabel(app.UIAxes2, 'Y');
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'Compass', 'Accelerometer', 'Rotation'};
            app.DropDown.Value = 'Compass';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'AltIMU-10';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 't [s]');
            ylabel(app.UIAxes, 'Y');
            app.DropDown2 = uidropdown(app.Tab);
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'Drop Down';
            app.StateButton2 = uibutton(app.Tab, 'state');
            app.StateButton2.ValueChangedFcn = createCallbackFcn(app, @StateButton2ValueChanged);
            app.StateButton2.Text = 'run com';
            app.StateButton3 = uibutton(app.Tab, 'state');
            app.StateButton3.ValueChangedFcn = createCallbackFcn(app, @StateButton3ValueChanged);
            app.StateButton3.Text = 'Plot';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'plot_data';
            app.LabelEditField = uilabel(app.Tab2);
            app.LabelEditField.Text = 'filename';
            app.EditField = uieditfield(app.Tab2, 'text');
            app.UIAxes5 = uiaxes(app.Tab2);
            title(app.UIAxes5, 'PPB of gas');
            xlabel(app.UIAxes5, 't [s]');
            ylabel(app.UIAxes5, 'Concentration');
            app.UIAxes6 = uiaxes(app.Tab2);
            title(app.UIAxes6, 'Title');
            xlabel(app.UIAxes6, 't [s]');
            ylabel(app.UIAxes6, 'Y');
            app.UIAxes7 = uiaxes(app.Tab2);
            title(app.UIAxes7, 'Title');
            xlabel(app.UIAxes7, 't [s]');
            ylabel(app.UIAxes7, 'Y');
            app.UIAxes8 = uiaxes(app.Tab2);
            title(app.UIAxes8, 'Title');
            xlabel(app.UIAxes8, 't [s]');
            ylabel(app.UIAxes8, 'Y');
            app.DropDown5 = uidropdown(app.Tab2);
            app.DropDown5.Items = {'Compass', 'Accelerometer', 'Rotation'};
            app.DropDown5.Value = 'Compass';
            app.Label = uilabel(app.Tab2);
            app.Label.Text = 'AltIMU-10';
            app.DropDown6 = uidropdown(app.Tab2);
            app.Label2 = uilabel(app.Tab2);
            app.Label2.Text = 'Drop Down';
            app.DropDown7 = uidropdown(app.Tab2);
            app.DropDown7.Items = {'CO', 'H2S', 'SO2', 'O3', 'NO2'};
            app.DropDown7.Value = 'CO';
            app.Label3 = uilabel(app.Tab2);
            app.Label3.Text = 'DGS';
            app.DropDown8 = uidropdown(app.Tab2);
            app.DropDown8.Items = {'temperature', 'humidity', 'pressure'};
            app.DropDown8.Value = 'temperature';
            app.Label4 = uilabel(app.Tab2);
            app.Label4.Text = 'BME280';
            app.Button = uibutton(app.Tab2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end